classdef pupil_crop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PupilCropareaLabel  matlab.ui.control.Label
        Tree                matlab.ui.container.Tree
        TrialsNode          matlab.ui.container.TreeNode
        SaveAreaButton      matlab.ui.control.Button
        FrameSlider         matlab.ui.control.Slider
        FrameSliderLabel    matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        frames
        roi
        line
    end

    methods (Access = private)

        function load_image(app,trial)
            pupil_path = fullfile(trial.path,'pupil_data');
            if isfolder(pupil_path)
                pupil_frames = dir(fullfile(pupil_path,'*.png'));
                app.FrameSlider.Limits =[1 length(pupil_frames)];
                app.frames = string(fullfile(pupil_path,{pupil_frames.name}))';
            else
                error(trial.Path + "does not have pupil data")
            end
            pupil = imread(app.frames(5));

            im = app.UIAxes.findobj('Type','Image');
            im.CData = pupil;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, trials)
            if nargin == 2
                for i = 1:numel(trials)
                    uitreenode(app.TrialsNode,'Text',string(trials(i).name),...
                        'NodeData',trials(i));
                end

                trial = app.TrialsNode.Children(1).NodeData;

                axis(app.UIAxes,'image')
                disableDefaultInteractivity(app.UIAxes);

                pupil_path = fullfile(trial.path,'pupil_data');

                app.FrameSlider.Value = 5;
                val = app.FrameSlider.Value;

                if isfolder(pupil_path)
                    pupil_frames = dir(fullfile(pupil_path,'*.png'));
                    app.frames = string(fullfile(pupil_path,{pupil_frames.name}))';
                    app.FrameSlider.Limits =[1 length(pupil_frames)];
                end
                pup = imread(app.frames(val));
                imshow(pup,'Parent',app.UIAxes);
                hold on
                if trial.has_var('pupil_crop')
                    crop = trial.load_var('pupil_crop');
                    app.roi = drawrectangle(app.UIAxes,'Position',crop);
                else
                    app.roi = drawrectangle(app.UIAxes,'Position',[200,200,350,330]);
                end

                if trial.has_var('eye_diameter')
                    eye = trial.load_var('eye_diameter');
                    app.line = drawline(app.UIAxes,'Position',eye,'Color','r');
                else
                    app.line = drawline(app.UIAxes,'Position',[200,200;350,330],'Color','r');
                end
            end
        end

        % Button pushed function: SaveAreaButton
        function SaveAreaButtonPushed(app, event)
            crop = app.roi.Position;
            eye_d = app.line.Position;
            trial = app.Tree.SelectedNodes.NodeData;
            trial.save_var('pupil_crop',crop)
            trial.save_var('eye_diameter',eye_d)
            disp("Crop position saved")
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            trial = app.Tree.SelectedNodes.NodeData;
            app.FrameSlider.Value = 5;
            app.load_image(trial)
            if trial.has_var('pupil_crop')
                crop = trial.load_var('pupil_crop');
                app.roi.Position = crop;
            end
            if trial.has_var('eye_diameter')
                eye = trial.load_var('eye_diameter');
                app.line.Position = eye;
            end
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = round(app.FrameSlider.Value);
            pupil = imread(app.frames(value));
            im = app.UIAxes.findobj('Type','Image');
            im.CData = pupil;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.SaveAreaButton = uibutton(app.UIFigure, 'push');
            app.SaveAreaButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAreaButtonPushed, true);
            app.SaveAreaButton.Text = 'Save Area';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.TrialsNode = uitreenode(app.Tree);
            app.TrialsNode.Text = 'Trials';
            app.PupilCropareaLabel = uilabel(app.UIFigure);
            app.PupilCropareaLabel.Text = 'Pupil Crop area';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pupil_crop(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end