classdef glyr_expression < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ClickontheRoItochangeBlacknoGlyRexpressionLabel  matlab.ui.control.Label
        RoIsmaskButton     matlab.ui.control.StateButton
        SelectNeuronRoIswoGlyRexpressionLabel  matlab.ui.control.Label
        FindTSeriesButton  matlab.ui.control.Button
        Tree               matlab.ui.container.Tree
        TseriesFoVNode     matlab.ui.container.TreeNode
        UITable            matlab.ui.control.Table
        MergeButton        matlab.ui.control.Button
        Ch2Button          matlab.ui.control.Button
        Ch1Button          matlab.ui.control.Button
        SaveButton         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ch1
        ch2
        merge
        mask
        rois_id
        rois
    end

    methods (Access = private)

        function [ch1,ch2,merge] = get_channels(app,ts)
            ch1 =  ts.load_var('img_avg_ch1_cy1');
            ch2 =  ts.load_var('img_avg_ch2_cy1');

            c = glyr.img.normalize(ch1);
            d = glyr.img.normalize(ch2);

            ch1 = imadjust(c,stretchlim(c,[0.03 0.995]),[]);
            ch2 = imadjust(d,stretchlim(d,[0.03 0.995]),[]);

            z = zeros(ts.img_dim(1),ts.img_dim(2),1);
            ch1 = cat(3,z,ch1,z);
            ch2 = cat(3,ch2,z,z);
            merge = ch1 +ch2;
            app.ch1 = ch1;
            app.ch2 = ch2;
            app.merge = merge;
        end

        function neu_id = get_rois_id(app,ts)
            rois_tab = ts.load_var('roi_table');
            neurons = rois_tab(rois_tab.type == "NS",:);
            neu_id = neurons.short_name;
            app.rois_id = neu_id;
        end

        function mask = get_rois_mask(app,ts)
            rois_tab = ts.load_var('roi_table');
            neurons = rois_tab(rois_tab.type == "NS",:);
            %mask = sum(cat(3,neurons.mask{:}),3);
            %mask = bwboundaries(mask);
            % when using bwboundaries with a mask that has all rois, it
            % detects the contact of rois boundaries and add it to the
            % array, which later cause an error when pairing roi ids  with
            % rois(lines)
            mask = cell(height(neurons),1);
            for i = 1:height(neurons)
                b = bwboundaries(neurons.mask{i});
                if numel(b) > 1
                    [~,idx] = max(cellfun(@length,b));
                    b = b(idx);
                end
                mask(i) = b;
            end
            app.mask = mask;
        end

        function load_data(app,ts)
            app.get_channels(ts);
            app.get_rois_id(ts);
            app.get_rois_mask(ts);
        end
    end

    methods (Access = public)

        function handle_roi(app,src,~)
            tab = app.UITable.Data;
            if  src.Color == [1,1,1]
                src.Color = 'k';
                tab.expression(tab.neu_id == src.Tag) = 0;
                app.UITable.Data = tab;
            else
                src.Color = 'w';
                tab.expression(tab.neu_id == src.Tag) = 1;
                app.UITable.Data = tab;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tss)
            if nargin == 2
                for i = 1:numel(tss)
                    uitreenode(app.TseriesFoVNode,'Text',string(tss(i).load_var('RFOV')),...
                        'NodeData',tss(i));
                end

                ts = app.TseriesFoVNode.Children(1).NodeData;

                app.load_data(ts);
                axis(app.UIAxes,'image')
                disableDefaultInteractivity(app.UIAxes);
                
                imshow(app.ch1,'Parent',app.UIAxes);

                if ts.has_var("glyr_expression")
                    glyr_exp = ts.load_var("glyr_expression");
                    app.UITable.Data = glyr_exp;
                else
                    neu_id = app.rois_id;
                    expression = ones(length(neu_id),1);
                    tab = table(neu_id,expression);
                    app.UITable.Data = tab;
                end
            else
                disp('Use "Find TSeries" button to load Tseries in the app')
            end

        end

        % Button pushed function: Ch1Button
        function Ch1ButtonPushed(app, event)
            im = app.UIAxes.findobj('Type','Image');
            im.CData = app.ch1;
        end

        % Button pushed function: Ch2Button
        function Ch2ButtonPushed(app, event)
            im = app.UIAxes.findobj('Type','Image');
            im.CData = app.ch2;
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            im = app.UIAxes.findobj('Type','Image');
            im.CData = app.merge;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            ts =  app.Tree.SelectedNodes.NodeData;
            ts.save_var('glyr_expression',app.UITable.Data)
            disp("Glyr_expression for FoV " + ts.load_var('fov') + " saved")
        end

        % Button pushed function: FindTSeriesButton
        function FindTSeriesButtonPushed(app, event)
            delete(app.TseriesFoVNode.Children);
            delete(app.UIAxes.Children);
            try
                [f,path] = uigetfile;
                tss_path = fullfile(path,f);
                t = load(tss_path);
                tss = t.dlocs';
                app.startupFcn(tss)
            catch ME
                warning(ME.message)
                tss = begonia.scantype.find_scans(uigetdir);
                app.startupFcn(tss)
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            % clean up
            delete(app.rois)
            app.rois = [];
            app.RoIsmaskButton.Value = 0;

            ts = app.Tree.SelectedNodes.NodeData;

            app.load_data(ts)

            im = app.UIAxes.findobj('Type','Image');
            im.CData = app.ch1;

            if ts.has_var("glyr_expression")
                glyr_exp = ts.load_var("glyr_expression");
                app.UITable.Data = glyr_exp;
            else
                neu_id = app.get_rois_id(ts);
                expression = ones(length(neu_id),1);
                tab = table(neu_id,expression);
                app.UITable.Data = tab;
            end
        end

        % Value changed function: RoIsmaskButton
        function RoIsmaskButtonValueChanged(app, event)
            value = app.RoIsmaskButton.Value;
            tab = app.UITable.Data;
            if value == 1
                if ~isempty(app.rois)
                    set(app.rois,'Visible','on')
                else
                    hold(app.UIAxes,"on")
                    for i = 1:length(app.mask)
                        p =  plot(app.UIAxes,app.mask{i}(:,2),...
                            app.mask{i}(:,1),'LineWidth',2,...
                            'ButtonDownFcn',@app.handle_roi);
                        p.Tag = app.rois_id(i);
                        if tab.expression(tab.neu_id == p.Tag) == 1
                            p.Color = 'w';
                        else
                            p.Color = 'k';
                        end
                    end
                    app.rois = app.UIAxes.findobj('Type','Line');
                end
            else
                if ~isempty(app.rois)
                    set(app.rois,'Visible','off')
                end
            end
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case '4'
                    if app.RoIsmaskButton.Value == 0
                        app.RoIsmaskButton.Value = 1;
                    else
                        app.RoIsmaskButton.Value = 0;
                    end
                    app.RoIsmaskButtonValueChanged(event)
                case '1'
                    app.Ch1ButtonPushed(event)
                case '2'
                    app.Ch2ButtonPushed(event)
                case '3'
                    app.MergeButtonPushed(event)
                case 's'
                    app.SaveButtonPushed(event)
                case 'o'
                    app.FindTSeriesButtonPushed(event)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'click ''s'''};
            app.SaveButton.Text = 'Save';
            app.Ch1Button = uibutton(app.UIFigure, 'push');
            app.Ch1Button.ButtonPushedFcn = createCallbackFcn(app, @Ch1ButtonPushed, true);
            app.Ch1Button.Tooltip = {'click ''1'''};
            app.Ch1Button.Text = 'Ch1';
            app.Ch2Button = uibutton(app.UIFigure, 'push');
            app.Ch2Button.ButtonPushedFcn = createCallbackFcn(app, @Ch2ButtonPushed, true);
            app.Ch2Button.Tooltip = {'click ''2'''};
            app.Ch2Button.Text = 'Ch2';
            app.MergeButton = uibutton(app.UIFigure, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Tooltip = {'click ''3'''};
            app.MergeButton.Text = 'Merge';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'roi_id'; 'expresion'};
            app.UITable.RowName = {};
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.TseriesFoVNode = uitreenode(app.Tree);
            app.TseriesFoVNode.Text = 'Tseries FoV';
            app.FindTSeriesButton = uibutton(app.UIFigure, 'push');
            app.FindTSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @FindTSeriesButtonPushed, true);
            app.FindTSeriesButton.Tooltip = {'click ''o'''};
            app.FindTSeriesButton.Text = 'Find TSeries';
            app.SelectNeuronRoIswoGlyRexpressionLabel = uilabel(app.UIFigure);
            app.SelectNeuronRoIswoGlyRexpressionLabel.Text = 'Select Neuron RoIs w/o GlyR expression';
            app.RoIsmaskButton = uibutton(app.UIFigure, 'state');
            app.RoIsmaskButton.ValueChangedFcn = createCallbackFcn(app, @RoIsmaskButtonValueChanged, true);
            app.RoIsmaskButton.Tooltip = {'click ''4'''};
            app.RoIsmaskButton.Text = 'RoIs mask';
            app.ClickontheRoItochangeBlacknoGlyRexpressionLabel = uilabel(app.UIFigure);
            app.ClickontheRoItochangeBlacknoGlyRexpressionLabel.Text = '* Click on the RoIs to change their classification    **Black = no GlyR expression  ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = glyr_expression(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end